classdef semfireIAlabeling_G2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        IniciarButton           matlab.ui.control.Button
        ImagemnumeroLabel       matlab.ui.control.Label
        ComearemEditFieldLabel  matlab.ui.control.Label
        ComearemEditField       matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
    end


    properties (Access = private)
        Property % Description
    end

    methods (Access = public)
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global increment;
            global h1;
            global corpus;
            global hold;
            hold = [];
            h1 = [];
            corpus = {};
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global increment;
            global corpus;
            global h1;
            [ir_sd_rect,rgb_sd_rect] = semfireIAparsing_G2(increment,increment);
            image_rgb = readImage(rgb_sd_rect{1});
            corpus{increment,1} = imresize(image_rgb,[360 480]);
            corpus{increment,2} = imresize(bw2rgb(h1),[360 480]);
            imwrite(imresize(image_rgb,[360 480]),['imgDir/img' num2str(increment) '.png'],'png');
            imwrite(imresize(bw2rgb(h1),[360 480]),['labelDir/label' num2str(increment) '.png'],'png');
            save('jakim1.mat','increment','corpus');
            delete(app);
            
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            global increment;
            global h1;
            global corpus;
            global hold;
            increment = app.ComearemEditField.Value
            app.ComearemEditField.Enable = 'off'
            app.Label.Text = num2str(increment);
            [ir_sd_rect,rgb_sd_rect] = semfireIAparsing_G2(increment,increment);
            image_rgb = readImage(rgb_sd_rect{1});
            imshow(imresize(image_rgb,[768 1024]));
            hold = imresize(image_rgb,[768 1024]);
            h1 = logical(zeros([768 1024]));
            finished = 'NO';
            i = 1;
            while strcmpi(finished,'NO')
              hFH(i) = imfreehand();
              finished = questdlg('Finished?', ...
                  'confirmation', ...
                  'YES', 'NO', 'UNDO', 'YES');
              if strcmpi(finished, 'UNDO')
                  delete(hFH(i))
                  finished = 'NO';
              elseif strcmpi(finished, 'YES')
                  h1 = h1 + logical(createMask(hFH(i)));
                  imshow(imfuse(hold,h1,'blend','Scaling','joint'))
                  finished = 'NO';
                    i = 1;
                  [ir_sd_rect,rgb_sd_rect] = semfireIAparsing_G2(increment,increment);
                image_rgb = readImage(rgb_sd_rect{1});
                corpus{increment,1} = imresize(image_rgb,[360 480]);
                corpus{increment,2} = imresize(bw2rgb(h1),[360 480]);
                imwrite(imresize(image_rgb,[360 480]),['imgDir/img' num2str(increment) '.png'],'png');
                imwrite(imresize(bw2rgb(h1),[360 480]),['labelDir/label' num2str(increment) '.png'],'png');
                increment = increment + 1
                app.Label.Text = num2str(increment);
                [ir_sd_rect,rgb_sd_rect] = semfireIAparsing_G2(increment,increment);
                image_rgb = readImage(rgb_sd_rect{1});
                imshow(imresize(image_rgb,[768 1024]));
                hold = imresize(image_rgb,[768 1024]);
                h1 = logical(zeros([768 1024]));
              else
                  h1 = h1 + logical(createMask(hFH(i)));
                  imshow(imfuse(hold,h1,'blend','Scaling','joint'))
                  i = i + 1;
              end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
            app.ImagemnumeroLabel = uilabel(app.UIFigure);
            app.ImagemnumeroLabel.Text = 'Imagem numero:';
            app.ComearemEditFieldLabel = uilabel(app.UIFigure);
            app.ComearemEditFieldLabel.Text = 'Começar em';
            app.ComearemEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComearemEditField.Value = 1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = semfireIAlabeling_G2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end